classdef DIPProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DigitalImageProcessorUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InterpolationTab               matlab.ui.container.Tab
        InterpolationPanel             matlab.ui.container.Panel
        ShrinkByFactorof2Button        matlab.ui.control.Button
        ZoomByFactorof2Button          matlab.ui.control.Button
        DegreesEditField               matlab.ui.control.NumericEditField
        DegreesEditFieldLabel          matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        RotateButton                   matlab.ui.control.Button
        WidthEditField_2               matlab.ui.control.NumericEditField
        WidthEditField_2Label          matlab.ui.control.Label
        HeightEditField_2              matlab.ui.control.NumericEditField
        HeightEditField_2Label         matlab.ui.control.Label
        ResizeNearestBilinearBicubicButton  matlab.ui.control.Button
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        ResizeImageButton              matlab.ui.control.Button
        FilteringTab                   matlab.ui.container.Tab
        FilteringPanel                 matlab.ui.container.Panel
        FactorSpinner_3                matlab.ui.control.Spinner
        FactorSpinner_3Label           matlab.ui.control.Label
        Spinner_3                      matlab.ui.control.Spinner
        BlurCertainPartButton          matlab.ui.control.Button
        SharpenImageUsingUnsharpMaskingButton  matlab.ui.control.Button
        ApplyLaplacianFilterButton     matlab.ui.control.Button
        DetectEdgesButton              matlab.ui.control.Button
        BlurImageButton                matlab.ui.control.Button
        ImageEnhancementTab            matlab.ui.container.Tab
        ApplyHistogramEqualizationGrayScaleButton_3  matlab.ui.control.Button
        ImageDropDown                  matlab.ui.control.DropDown
        ImageDropDown_2Label           matlab.ui.control.Label
        ShowHistogramAfterEqualizationButton  matlab.ui.control.Button
        ApplyHistogramEqualizationColoredButton_2  matlab.ui.control.Button
        FactorSpinner_2                matlab.ui.control.Spinner
        FactorSpinner_2Label           matlab.ui.control.Label
        FactorSpinner                  matlab.ui.control.Spinner
        FactorSpinnerLabel             matlab.ui.control.Label
        EnhanceContrastofBrighterRegionsLabel  matlab.ui.control.Label
        PowerTransformButton           matlab.ui.control.Button
        ReduceContrastofBrighterRegionsLabel  matlab.ui.control.Label
        LogTransformButton             matlab.ui.control.Button
        IncreaseWhiteDetailinDarkerRegionsLabel  matlab.ui.control.Label
        DigitalNegativeButton          matlab.ui.control.Button
        ImageRestorationTab            matlab.ui.container.Tab
        SpeckleNoiseButton             matlab.ui.control.Button
        GaussainFilterButton           matlab.ui.control.Button
        PoissonNoiseButton             matlab.ui.control.Button
        GaussianNoiseButton            matlab.ui.control.Button
        SaltPepperButton               matlab.ui.control.Button
        SelectNoiseLabel               matlab.ui.control.Label
        SelectRestorationMethodLabel   matlab.ui.control.Label
        MedianFilterButton             matlab.ui.control.Button
        MeanFilterButton               matlab.ui.control.Button
        WeinerFilterButton             matlab.ui.control.Button
        FrequencyDomainTab             matlab.ui.container.Tab
        GaussainHPFButton              matlab.ui.control.Button
        ButterworthHPFButton           matlab.ui.control.Button
        IdealHPFButton                 matlab.ui.control.Button
        ImageSharpeningLabel           matlab.ui.control.Label
        ImageSmoothingLabel            matlab.ui.control.Label
        GaussainLPFButton              matlab.ui.control.Button
        ButterworthLPFButton           matlab.ui.control.Button
        IdealLPFButton                 matlab.ui.control.Button
        DigitalImageProcessorLabel     matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global a;
            a = imread("cameraman.tif");
            imshow(a, 'parent', app.UIAxes);
        end

        % Callback function
        function LoadImageButtonPushed(app, event)
            global a;
            global path;
            %filter = {'*.jpg';'*.jpeg';'*.png';};
            %[FileName, PathName] = uigetfile(filter, 'File Selector');
            %path = strcat(PathName,FileName);
            %path = "D:\University Files\6th Semester\Digital Image Processing\Chapter2Code_Interpolation-20220318\original_image.tif";
            a = imread("cameraman.tif");
            imshow(a, 'parent', app.UIAxes);
        end

        % Callback function
        function ResetImageButtonPushed(app, event)
            global a;
            imshow(a, 'parent', app.UIAxes_2);
        end

        % Button pushed function: ResizeImageButton
        function ResizeImageButtonPushed(app, event)
            global a;
            img = a;
            val = app.DropDown.Value;
            h = app.HeightEditField.Value;
            w = app.WidthEditField.Value;
            
            if strcmp(val, 'Nearest')
                img = imresize(img, [h,w],'nearest');
            elseif strcmp(val, 'Bilinear') 
                img = imresize(img, [h,w],'bilinear'); 
            else 
                img = imresize(img, [h,w],'bicubic'); 
            end
            
            imshow(img, 'parent', app.UIAxes_2);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            global a;
            img = a;
            val = app.DropDown_2.Value;
            deg = app.DegreesEditField.Value;
            
            if strcmp(val, 'Nearest')
                img = imrotate(img, deg,'nearest');
            elseif strcmp(val, 'Bilinear') 
                img = imrotate(img, deg,'bilinear'); 
            else 
                img = imrotate(img, deg,'bicubic'); 
            end
            
            imshow(img, 'parent', app.UIAxes_2);  
        end

        % Button pushed function: ZoomByFactorof2Button
        function ZoomByFactorof2ButtonPushed(app, event)
            global a;
            img = a;
            [m, n] = size(img);
            for i = 1:2*m
                for j = 1:2*n
                    p = i - floor(i / 2);
                    q = j - floor(j / 2);
                    
                    b(i, j) = a(p, q);
                end
            end
            figure;imshow(a)
            title('Original')
 
            figure;imshow(b);
        end

        % Button pushed function: ShrinkByFactorof2Button
        function ShrinkByFactorof2ButtonPushed(app, event)
            global a;
            img = a;
            factor = 2;
            s = size(img);
            s1 = s/factor;
            k=1;
            l=1;
            for i=1:s1
                for j=1:s1
                    d(i,j)=img(k,l);
                    l=l+factor;
                end
                l=1;
                k=k+factor;
            end
            figure
            imshow(img);
            title('Original')
            figure(2)
            imshow(d);
            title('Shrinked')
        end

        % Size changed function: DigitalImageProcessorUIFigure
        function DigitalImageProcessorUIFigureSizeChanged(app, event)
            position = app.DigitalImageProcessorUIFigure.Position;
            
        end

        % Button pushed function: ResizeNearestBilinearBicubicButton
        function ResizeNearestBilinearBicubicButtonPushed(app, event)
               global a;
            img = a;
            h = app.HeightEditField_2.Value;
            w = app.WidthEditField_2.Value;
            warning off;
            
            figure, subplot(221), imshow(img), title('Original')
            I=imresize(img, [h,w],'nearest');
            subplot(222), imshow(I), title('Nearest')
            I=imresize(img, [h,w],'bilinear');
            subplot(223), imshow(I), title('Bilinear')
            I=imresize(img, [h,w],'bicubic');
            subplot(224), imshow(I), title('Bicubic')
        end

        % Button down function: FilteringTab
        function FilteringTabButtonDown(app, event)
            global filt_img;
            filt_img = imread('football.jpg');
            imshow(filt_img, 'parent', app.UIAxes);
        end

        % Callback function
        function ImageEnhancementTabButtonDown(app, event)
            global ie_img;
            ie_img = imread('golden.jpg');
            imshow(ie_img, 'parent',  app.UIAxes');
        end

        % Button pushed function: 
        % ApplyHistogramEqualizationGrayScaleButton_3
        function ApplyHistogramEqualizationGrayScaleButtonPushed(app, event)
            global ie_img;
            img = ie_img;
            img = rgb2gray(img);
            j = histeq(img);
            imshow(j, 'parent', app.UIAxes_2);
        end

        % Callback function: FilteringTab, InterpolationTab
        function InterpolationTabButtonDown(app, event)
            global a;
            imshow(a, 'parent', app.UIAxes);
        end

        % Button pushed function: 
        % ApplyHistogramEqualizationColoredButton_2
        function ApplyHistogramEqualizationColoredButtonPushed(app, event)
            global ie_img;
            img = ie_img;
            img = rgb2hsv(img);
            img(:,:,3) = histeq(img(:,:,3));
            rs = hsv2rgb(img);
            
            imshow(rs, 'parent', app.UIAxes_2);
        end

        % Callback function
        function ShowHistogramAfterEqualizationButtonPushed(app, event)
            global ie_img;
            img = ie_img;
            selected_value = app.ImageDropDown.Value;
            if strcmp(selected_value, 'GrayScale')
                img = rgb2gray(img);
                subplot(2, 2, 1);
                imshow(img);
                title('Original Grayscale Image');
                subplot(2, 2, 3);
                imhist(img);
                title('Histogram of Original Grayscale Image');
                j = histeq(img);
                subplot(2, 2, 2);
                imshow(j);
                title('Image after histogram equalization');
                subplot(2, 2, 4);
                imhist(j);
                title('Histogram after histogram equalization');
            else
                subplot(2, 2, 1);
                imshow(img);
                title('Original Colored Image');
                img = rgb2hsv(img);
                subplot(2, 2, 3);
                imhist(img);
                title('Histogram of Original Colored Image');
                img(:,:,3) = histeq(img(:,:,3));
                rs = hsv2rgb(img);
                subplot(2, 2, 2);
                imshow(rs);
                title('Image after histogram equalization');
                subplot(2, 2, 4);
                imhist(rs);
                title('Histogram after histogram equalization');
            end
        end

        % Button down function: ImageEnhancementTab
        function ImageEnhancementTabButtonDown2(app, event)
            %global con_img;
            %con_img = imread('blocks.jpeg');
            %imshow(con_img, 'parent', app.UIAxes);
            global ie_img;
            ie_img = imread('office_3.jpg');
            imshow(ie_img, 'parent',  app.UIAxes');
        end

        % Button pushed function: DigitalNegativeButton
        function DigitalNegativeButtonPushed(app, event)
            global ie_img;
            img = ie_img;
            img = rgb2gray(img);
            img1 = 255-img;
            imshow(img1, 'parent', app.UIAxes_2);
        end

        % Button pushed function: LogTransformButton
        function LogTransformButtonPushed(app, event)
            global ie_img;
            img = ie_img;
            img = im2double(img);
            img_double = img;
            [r, c] = size(img);
            factor = app.FactorSpinner.Value;
            for i = 1:r
                for j = 1:c
                    img_double(i, j) = factor * log(1 + img(i, j));
                end
            end
            imshow(img_double, 'parent', app.UIAxes_2);
        end

        % Button pushed function: PowerTransformButton
        function PowerTransformButtonPushed(app, event)
            global ie_img;
            img = ie_img;
            img = im2double(img);
            img_double = img;
            [r, c] = size(img);
            factor = app.FactorSpinner_2.Value;
            for i = 1:r
                for j = 1:c
                    img_double(i, j) = factor * img(i, j) ^ 2;
                end
            end
            imshow(img_double, 'parent', app.UIAxes_2);
        end

        % Button pushed function: BlurImageButton
        function BlurImageButtonPushed(app, event)
            global filt_img;
            m = filt_img;
            val = app.FactorSpinner_3.Value;
            ag = fspecial('average', [val, val]);
            g = imfilter(m, ag);
            imshow(g, 'parent', app.UIAxes_2);
        end

        % Callback function
        function AddNoiseSaltPepperButtonPushed(app, event)
            global filt_img;
            J=filt_img;
            val = app.Spinner.Value;
            J = imnoise(J, 'salt & pepper', val);
            imshow(J, 'parent', app.UIAxes_2);
        end

        % Button pushed function: BlurCertainPartButton
        function BlurCertainPartButtonPushed(app, event)
            global filt_img;
            warning off;
            img = filt_img;
            imshow(img);
            [j,rect] = imcrop(img);
            ag = fspecial('average', [20,20]);
            g = imfilter(img, ag);
            g( rect(2):rect(2)+rect(4),rect(1):rect(1)+rect(3), :)=j;
            imshow(g);
        end

        % Button pushed function: DetectEdgesButton
        function DetectEdgesButtonPushed(app, event)
            global filt_img;
            e = im2gray(filt_img);
            w=edge(e, 'sobel');
            imshow(w, 'parent', app.UIAxes_2);
        end

        % Button pushed function: ApplyLaplacianFilterButton
        function ApplyLaplacianFilterButtonPushed(app, event)
            global filt_img;
            img = filt_img;
            lab=[0 1 0;1 -4 1;0 1 0];
            result = imfilter(img, lab);
            imshow(result, 'parent', app.UIAxes_2); 
        end

        % Button pushed function: 
        % SharpenImageUsingUnsharpMaskingButton
        function SharpenImageUsingUnsharpMaskingButtonPushed(app, event)
            %highpass filter
            %those pixels having highest intensities will pass, and low
            %intensity ones will be attenuated
            global filt_img;
            img = filt_img;
            val = app.Spinner_3.Value;
            ag = fspecial('average', [val, val]);
            g = imfilter(img, ag);
            g = imsubtract(img, g);
            x = imadd(img, g);
            imshow(x, 'parent', app.UIAxes_2); 
        end

        % Button down function: ImageRestorationTab
        function ImageRestorationTabButtonDown(app, event)
            global without_noise;
            without_noise = imread('pout.tif');
            imshow(without_noise, 'parent', app.UIAxes);
        end

        % Button pushed function: WeinerFilterButton
        function WeinerFilterButtonPushed(app, event)
            global noise_img;
            w = wiener2(noise_img, [5, 5]);
            imshow(w, 'parent', app.UIAxes_2);
        end

        % Button pushed function: MeanFilterButton
        function MeanFilterButtonPushed(app, event)
            global noise_img;
            img = noise_img;
            mf = ones(3, 3) / 9;
            noise_free = imfilter(img, mf);
            imshow(noise_free, 'parent', app.UIAxes_2);
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            global noise_img;
            J = noise_img;
            p = medfilt3(J, [5,5,3]);
            imshow(p, 'parent', app.UIAxes_2);
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            global without_noise;
            global noise_img;
            noise_img = imnoise(without_noise, 'salt & pepper', 0.1);
            imshow(noise_img, 'parent', app.UIAxes);
        end

        % Button pushed function: GaussianNoiseButton
        function GaussianNoiseButtonPushed(app, event)
            global without_noise;
            noise_img = imnoise(without_noise,'gaussian',0.02,0.2);
            imshow(noise_img, 'parent', app.UIAxes);
        end

        % Button pushed function: GaussainFilterButton
        function GaussainFilterButtonPushed(app, event)
            global noise_img;
            sigma = 1;
            W = 0;
            kernel = zeros(5, 5);
            for i = 1:5
                for j=1:5
                    sq_dist = (i-3)^2 + (j-3)^2;
                    kernel(i, j) = exp(-1*(sq_dist)/(2*sigma*sigma));
                    W = W + kernel(i, j);
                end
            end
            kernel = kernel / W;
            [m, n] = size(noise_img);
            output = zeros(m, n);
            Im = padarray(noise_img, [2 ,2]);
            
            for i = 1:m
                for j = 1:n
                    temp = Im(i:i+4, j:j+4);
                    temp = double(temp);
                    conv = temp.*kernel;
                    output(i, j) = sum(conv(:));
                end
            end
            
            output = uint8(output);
            imshow(output, 'parent', app.UIAxes_2);
        end

        % Button pushed function: PoissonNoiseButton
        function PoissonNoiseButtonPushed(app, event)
            global without_noise;
            global noise_img;
            noise_img = imnoise(without_noise, 'poisson');
            imshow(noise_img, 'parent', app.UIAxes);
        end

        % Button pushed function: SpeckleNoiseButton
        function SpeckleNoiseButtonPushed(app, event)
            global without_noise;
            global noise_img;
            noise_img = imnoise(without_noise, 'speckle');
            imshow(noise_img, 'parent', app.UIAxes);
        end

        % Button down function: FrequencyDomainTab
        function FrequencyDomainTabButtonDown(app, event)
            global freq_img;
            freq_img = imread('cameraman.tif');
            imshow(freq_img, 'parent', app.UIAxes);
        end

        % Button pushed function: IdealLPFButton
        function IdealLPFButtonPushed(app, event)
            global freq_img;
            a = im2double(freq_img); 
            subplot(2,3,1);
            imshow(a);
            title('Input Image');
            
            [m,n] = size(a);  % size of input image
            D0 = 50; % Assigning Cut-off Frequency  
            A = fft2(a);   %fourier transform of input image
            subplot(2,3,2);
            imshow(uint8(abs(A)));
            title('F.T. of i/p without shift');
            
            A_shift = fftshift(A);  %shifting origin
            A_real = abs(A_shift);  %Real part of A_shift (Freq domain repres of image)
            
            subplot(2,3,3);
            imshow(uint8(A_real));
            title('F.T. of i/p after shift');
            
            A_low = zeros(m,n);
            d = zeros(m,n);
            for u=1:m
                for v=1:n
                    d(u,v)=sqrt((u-(m/2))^2+(v-(n/2))^2);
                    if d(u,v) <= D0
                        A_low(u,v)=A_shift(u,v);
                        filt(u,v) = 1;
                    else
                        A_low(u,v)=0; 
                        filt(u,v) = 0;
                    end
                end
            end
            
            subplot(2,3,4);
            imshow(filt)
            title('Ideal Low pass filter')
            
            subplot(2,3,5);
            mesh(filt)
            title('surface plot LPF')
            B = fftshift(A_low);  %Reshifting the origin of filtered image
            B_inverse = ifft2(B); %Taking inverse fourier transform
            B_real = abs(B_inverse);%Taking real part(Low pass output image)
            
            subplot(2,3,6);
            imshow(B_real);
            title('Ideal Low pass image');
            imshow(B_real, 'parent', app.UIAxes_2);
        end

        % Button pushed function: ButterworthLPFButton
        function ButterworthLPFButtonPushed(app, event)
            global freq_img;
            a = im2double(freq_img); 
            subplot(2,3,1);
            imshow(a);
            title('Input Image');
            
            [m,n] = size(a);  % size of input image
            D0 = 50; % Assigning Cut-off Frequency  
            A = fft2(a);   %fourier transform of input image
            subplot(2,3,2);
            imshow(uint8(abs(A)));
            title('F.T. of i/p without shift');
            
            A_shift = fftshift(A);  %shifting origin
            A_real = abs(A_shift);  %Real part of A_shift (Freq domain repres of image)
            
            subplot(2,3,3);
            imshow(uint8(A_real));
            title('F.T. of i/p after shift');
            
            A_low = zeros(m,n);
            d = zeros(m,n);
            order = 1;
            
            for u=1:m
                for v=1:n
                    d(u,v)=sqrt((u-(m/2))^2+(v-(n/2))^2);
                    H(u,v) = 1/((1+d(u,v)/D0)^(2*order));   
                end
            end
            
            subplot(2,3,4);
            imshow(H)
            title('Butterworth Low pass filter')
            
            subplot(2,3,5);
            mesh(H)
            title('surface plot BLPF')
            
            butter_LPF = A_shift.*H;  
            butter_LPF_inverse = ifft2(butter_LPF); 
            butter_LPF_real = abs(butter_LPF_inverse);
            
            subplot(2,3,6);
            imshow(butter_LPF_real);
            title('Butterworth Low pass image');
            imshow(butter_LPF_real, 'parent', app.UIAxes_2);
        end

        % Button pushed function: GaussainLPFButton
        function GaussainLPFButtonPushed(app, event)
            global freq_img;
            a = im2double(freq_img); 
            subplot(2,3,1);
            imshow(a);
            title('Input Image');
            
            [m,n] = size(a);  % size of input image
            D0 = 50; % Assigning Cut-off Frequency  
            A = fft2(a);   %fourier transform of input image
            subplot(2,3,2);
            imshow(uint8(abs(A)));
            title('F.T. of i/p without shift');
            
            A_shift = fftshift(A);  %shifting origin
            A_real = abs(A_shift);  %Real part of A_shift (Freq domain repres of image)
            
            subplot(2,3,3);
            imshow(uint8(A_real));
            title('F.T. of i/p after shift');
            
            A_low = zeros(m,n);
            d = zeros(m,n);
            
            for u=1:m
                for v=1:n
                    d=sqrt((u-m/2).^2+(v-n/2).^2);
                    H(u,v) = exp(-(d^2)/(2*D0.^2));   
                end
            end
            
            H_low = A_shift.*H;  
            H_low_real = H.*A_real;
            H_low_shift = fftshift(H_low);
            H_low_image = ifft2(H_low_shift);
            
            subplot(2,3,4);
            imshow(H)
            title('Gaussain Low pass filter')
            
            subplot(2,3,5);
            mesh(H)
            title('surface plot GLPF')
            
            subplot(2,3,6);
            imshow(abs(H_low_image));
            title('Gaussian Low pass image');
            imshow(abs(H_low_image), 'parent', app.UIAxes_2);
        end

        % Button pushed function: IdealHPFButton
        function IdealHPFButtonPushed(app, event)
            global freq_img;
            a = im2double(freq_img); 
            subplot(2,3,1);
            imshow(a);
            title('Input Image');
            
            [m,n] = size(a);  % size of input image
            D0 = 10; % Assigning Cut-off Frequency  
            A = fft2(a);   %fourier transform of input image
            subplot(2,3,2);
            imshow(uint8(abs(A)));
            title('F.T. of i/p without shift');
            
            A_shift = fftshift(A);  %shifting origin
            A_real = abs(A_shift);  %Real part of A_shift (Freq domain repres of image)
            
            subplot(2,3,3);
            imshow(uint8(A_real));
            title('F.T. of i/p after shift');
            
            A_high = zeros(m,n);
            D = zeros(m,n);
            for u=1:m
                for v=1:n
                    D(u,v)=sqrt((u-(m/2))^2+(v-(n/2))^2);
                    if D(u,v) <= D0
                        A_high(u,v) = 0;
                        filt(u,v) = 0;
                    else
                        A_high(u,v) = A_shift(u,v); 
                        filt(u,v) = 1;
                    end
                end
            end
            
            subplot(2,3,4);
            imshow(filt)
            title('Ideal High pass filter')
            
            subplot(2,3,5);
            mesh(filt)
            title('surface plot HPF')
            
            B = fftshift(A_high);  %Reshifting the origin of filtered image
            B_inverse = ifft2(B); %Taking inverse fourier transform
            B_real = abs(B_inverse);%Taking real part(Low pass output image)
            
            subplot(2,3,6);
            imshow(B_real);
            title('High pass image');
            imshow(B_real, 'parent', app.UIAxes_2);
        end

        % Button pushed function: 
        % ShowHistogramAfterEqualizationButton
        function ShowHistogramAfterEqualizationButtonPushed2(app, event)
            global ie_img;
            img = ie_img;
            selected_value = app.ImageDropDown.Value;
            if strcmp(selected_value, 'GrayScale')
                img = rgb2gray(img);
                subplot(2, 2, 1);
                imshow(img);
                title('Original Grayscale Image');
                subplot(2, 2, 3);
                imhist(img);
                title('Histogram of Original Grayscale Image');
                j = histeq(img);
                subplot(2, 2, 2);
                imshow(j);
                title('Image after histogram equalization');
                subplot(2, 2, 4);
                imhist(j);
                title('Histogram after histogram equalization');
            else
                subplot(2, 2, 1);
                imshow(img);
                title('Original Colored Image');
                img = rgb2hsv(img);
                subplot(2, 2, 3);
                imhist(img);
                title('Histogram of Original Colored Image');
                img(:,:,3) = histeq(img(:,:,3));
                rs = hsv2rgb(img);
                subplot(2, 2, 2);
                imshow(rs);
                title('Image after histogram equalization');
                subplot(2, 2, 4);
                imhist(rs);
                title('Histogram after histogram equalization');
            end
        end

        % Button pushed function: ButterworthHPFButton
        function ButterworthHPFButtonPushed(app, event)
            global freq_img;
            a = im2double(freq_img); 
            subplot(2,3,1);
            imshow(a);
            title('Input Image');
            
            [m,n] = size(a);  % size of input image
            D0 = 30;   
            A = fft2(a);   %fourier transform of input image
            subplot(2,3,2);
            imshow(uint8(abs(A)));
            title('F.T. of i/p without shift');
            
            A_shift = fftshift(A);  %shifting origin
            A_real = abs(A_shift);  %Real part of A_shift (Freq domain repres of image)
            
            subplot(2,3,3);
            imshow(uint8(A_shift));
            title('F.T. of i/p after shift');
            
            d = zeros(m,n);
            order = 4;
            
            for u=1:m
                for v=1:n
                    d(u,v)=sqrt((u-(m/2))^2+(v-(n/2))^2);
                    H(u,v) = 1/((1+(D0/d(u,v))^(2*order)));   
                end
            end
            
            butter_HPF = A_shift.*H;  
            butter_HPF_inverse = ifft2(butter_HPF); 
            butter_HPF_real = abs(butter_HPF_inverse);
            
            subplot(2,3,4);
            imshow(H)
            title('Butterworth High pass filter')
            
            subplot(2,3,5);
            mesh(H)
            title('surface plot BHPF')
            
            subplot(2,3,6);
            imshow(butter_HPF_real);
            title('Butterworth High pass image');
            imshow(butter_HPF_real, 'parent', app.UIAxes_2);
        end

        % Button pushed function: GaussainHPFButton
        function GaussainHPFButtonPushed(app, event)
            global freq_img;
            a = im2double(freq_img); 
            subplot(2,3,1);
            imshow(a);
            title('Input Image');
            
            [m,n] = size(a);  % size of input image
            D0 = 30; % Assigning Cut-off Frequency  
            A = fft2(a);   %fourier transform of input image
            subplot(2,3,2);
            imshow(uint8(abs(A)));
            title('F.T. of i/p without shift');
            
            A_shift = fftshift(A);  %shifting origin
            A_real = abs(A_shift);  %Real part of A_shift (Freq domain repres of image)
            
            subplot(2,3,3);
            imshow(uint8(A_real));
            title('F.T. of i/p after shift');
            
            d = zeros(m,n);
            
            for u=1:m
                for v=1:n
                    d=sqrt((u-m/2).^2+(v-n/2).^2);
                    H(u,v) = 1 - exp(-(d^2)/(2*D0.^2));   
                end
            end
            
            G_high = A_shift.*H;  
            G_high_real = H.*A_real;
            G_high_shift = fftshift(G_high);
            G_high_image = ifft2(G_high_shift);
            
            subplot(2,3,4);
            imshow(H)
            title('Gaussain Low pass filter')
            
            subplot(2,3,5);
            mesh(H)
            title('surface plot GLPF')
            
            subplot(2,3,6);
            imshow(abs(G_high_image));
            title('Gaussian Low pass image');
            imshow(abs(G_high_image), 'parent', app.UIAxes_2);
        end

        % Size changed function: ImageEnhancementTab
        function ImageEnhancementTabSizeChanged(app, event)
            position = app.ImageEnhancementTab.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DigitalImageProcessorUIFigure = uifigure('Visible', 'off');
            app.DigitalImageProcessorUIFigure.Name = 'Digital Image Processor';
            app.DigitalImageProcessorUIFigure.SizeChangedFcn = createCallbackFcn(app, @DigitalImageProcessorUIFigureSizeChanged, true);
            app.UIAxes = uiaxes(app.DigitalImageProcessorUIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.DigitalImageProcessorUIFigure);
            title(app.UIAxes_2, 'Result')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.DigitalImageProcessorLabel = uilabel(app.DigitalImageProcessorUIFigure);
            app.DigitalImageProcessorLabel.Text = 'Digital Image Processor';
            app.TabGroup = uitabgroup(app.DigitalImageProcessorUIFigure);
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.InterpolationTab.ButtonDownFcn = createCallbackFcn(app, @InterpolationTabButtonDown, true);
            app.InterpolationPanel = uipanel(app.InterpolationTab);
            app.InterpolationPanel.Title = 'Interpolation';
            app.ResizeImageButton = uibutton(app.InterpolationPanel, 'push');
            app.ResizeImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeImageButtonPushed, true);
            app.ResizeImageButton.Text = 'Resize Image ';
            app.DropDown = uidropdown(app.InterpolationPanel);
            app.DropDown.Items = {'Nearest', 'Bilinear', 'Bicubic'};
            app.DropDown.Value = 'Nearest';
            app.HeightEditFieldLabel = uilabel(app.InterpolationPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.InterpolationPanel, 'numeric');
            app.HeightEditField.Value = 300;
            app.WidthEditFieldLabel = uilabel(app.InterpolationPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.InterpolationPanel, 'numeric');
            app.WidthEditField.Value = 300;
            app.ResizeNearestBilinearBicubicButton = uibutton(app.InterpolationPanel, 'push');
            app.ResizeNearestBilinearBicubicButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeNearestBilinearBicubicButtonPushed, true);
            app.ResizeNearestBilinearBicubicButton.Text = 'Resize (Nearest, Bilinear, Bicubic)';
            app.HeightEditField_2Label = uilabel(app.InterpolationPanel);
            app.HeightEditField_2Label.Text = 'Height';
            app.HeightEditField_2 = uieditfield(app.InterpolationPanel, 'numeric');
            app.HeightEditField_2.Value = 300;
            app.WidthEditField_2Label = uilabel(app.InterpolationPanel);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditField_2 = uieditfield(app.InterpolationPanel, 'numeric');
            app.WidthEditField_2.Value = 300;
            app.RotateButton = uibutton(app.InterpolationPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.DropDown_2 = uidropdown(app.InterpolationPanel);
            app.DropDown_2.Items = {'Nearest', 'Bilinear', 'Bicubic'};
            app.DropDown_2.Value = 'Nearest';
            app.DegreesEditFieldLabel = uilabel(app.InterpolationPanel);
            app.DegreesEditFieldLabel.Text = 'Degrees';
            app.DegreesEditField = uieditfield(app.InterpolationPanel, 'numeric');
            app.DegreesEditField.Value = 45;
            app.ZoomByFactorof2Button = uibutton(app.InterpolationPanel, 'push');
            app.ZoomByFactorof2Button.ButtonPushedFcn = createCallbackFcn(app, @ZoomByFactorof2ButtonPushed, true);
            app.ZoomByFactorof2Button.Text = 'Zoom (By Factor of 2)';
            app.ShrinkByFactorof2Button = uibutton(app.InterpolationPanel, 'push');
            app.ShrinkByFactorof2Button.ButtonPushedFcn = createCallbackFcn(app, @ShrinkByFactorof2ButtonPushed, true);
            app.ShrinkByFactorof2Button.Text = 'Shrink (By Factor of 2)';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.SizeChangedFcn = createCallbackFcn(app, @InterpolationTabButtonDown, true);
            app.FilteringTab.Title = 'Filtering';
            app.FilteringTab.ButtonDownFcn = createCallbackFcn(app, @FilteringTabButtonDown, true);
            app.FilteringPanel = uipanel(app.FilteringTab);
            app.FilteringPanel.Title = 'Filtering';
            app.BlurImageButton = uibutton(app.FilteringPanel, 'push');
            app.BlurImageButton.ButtonPushedFcn = createCallbackFcn(app, @BlurImageButtonPushed, true);
            app.BlurImageButton.Text = 'Blur Image';
            app.DetectEdgesButton = uibutton(app.FilteringPanel, 'push');
            app.DetectEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectEdgesButtonPushed, true);
            app.DetectEdgesButton.Text = 'Detect Edges';
            app.ApplyLaplacianFilterButton = uibutton(app.FilteringPanel, 'push');
            app.ApplyLaplacianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLaplacianFilterButtonPushed, true);
            app.ApplyLaplacianFilterButton.Text = 'Apply Laplacian Filter';
            app.SharpenImageUsingUnsharpMaskingButton = uibutton(app.FilteringPanel, 'push');
            app.SharpenImageUsingUnsharpMaskingButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenImageUsingUnsharpMaskingButtonPushed, true);
            app.SharpenImageUsingUnsharpMaskingButton.Text = 'Sharpen Image (Using Unsharp Masking)';
            app.BlurCertainPartButton = uibutton(app.FilteringPanel, 'push');
            app.BlurCertainPartButton.ButtonPushedFcn = createCallbackFcn(app, @BlurCertainPartButtonPushed, true);
            app.BlurCertainPartButton.Text = 'Blur Certain Part';
            app.Spinner_3 = uispinner(app.FilteringPanel);
            app.Spinner_3.Limits = [1 100];
            app.Spinner_3.Value = 1;
            app.FactorSpinner_3Label = uilabel(app.FilteringPanel);
            app.FactorSpinner_3Label.Text = 'Factor';
            app.FactorSpinner_3 = uispinner(app.FilteringPanel);
            app.FactorSpinner_3.Limits = [1 10];
            app.FactorSpinner_3.Value = 1;
            app.ImageEnhancementTab = uitab(app.TabGroup);
            app.ImageEnhancementTab.SizeChangedFcn = createCallbackFcn(app, @ImageEnhancementTabSizeChanged, true);
            app.ImageEnhancementTab.Title = 'Image Enhancement';
            app.ImageEnhancementTab.ButtonDownFcn = createCallbackFcn(app, @ImageEnhancementTabButtonDown2, true);
            app.DigitalNegativeButton = uibutton(app.ImageEnhancementTab, 'push');
            app.DigitalNegativeButton.ButtonPushedFcn = createCallbackFcn(app, @DigitalNegativeButtonPushed, true);
            app.DigitalNegativeButton.Text = 'Digital Negative';
            app.IncreaseWhiteDetailinDarkerRegionsLabel = uilabel(app.ImageEnhancementTab);
            app.IncreaseWhiteDetailinDarkerRegionsLabel.Text = 'Increase White Detail in Darker Regions';
            app.LogTransformButton = uibutton(app.ImageEnhancementTab, 'push');
            app.LogTransformButton.ButtonPushedFcn = createCallbackFcn(app, @LogTransformButtonPushed, true);
            app.LogTransformButton.Text = 'Log Transform';
            app.ReduceContrastofBrighterRegionsLabel = uilabel(app.ImageEnhancementTab);
            app.ReduceContrastofBrighterRegionsLabel.Text = 'Reduce Contrast of Brighter Regions';
            app.PowerTransformButton = uibutton(app.ImageEnhancementTab, 'push');
            app.PowerTransformButton.ButtonPushedFcn = createCallbackFcn(app, @PowerTransformButtonPushed, true);
            app.PowerTransformButton.Text = 'Power Transform';
            app.EnhanceContrastofBrighterRegionsLabel = uilabel(app.ImageEnhancementTab);
            app.EnhanceContrastofBrighterRegionsLabel.Text = 'Enhance Contrast of Brighter Regions';
            app.FactorSpinnerLabel = uilabel(app.ImageEnhancementTab);
            app.FactorSpinnerLabel.Text = 'Factor';
            app.FactorSpinner = uispinner(app.ImageEnhancementTab);
            app.FactorSpinner.Limits = [0 10];
            app.FactorSpinner.Value = 1;
            app.FactorSpinner_2Label = uilabel(app.ImageEnhancementTab);
            app.FactorSpinner_2Label.Text = 'Factor';
            app.FactorSpinner_2 = uispinner(app.ImageEnhancementTab);
            app.FactorSpinner_2.Limits = [0 10];
            app.FactorSpinner_2.Value = 1;
            app.ApplyHistogramEqualizationColoredButton_2 = uibutton(app.ImageEnhancementTab, 'push');
            app.ApplyHistogramEqualizationColoredButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyHistogramEqualizationColoredButtonPushed, true);
            app.ApplyHistogramEqualizationColoredButton_2.Text = 'Apply Histogram Equalization (Colored)';
            app.ShowHistogramAfterEqualizationButton = uibutton(app.ImageEnhancementTab, 'push');
            app.ShowHistogramAfterEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramAfterEqualizationButtonPushed2, true);
            app.ShowHistogramAfterEqualizationButton.Text = 'Show Histogram After Equalization';
            app.ImageDropDown_2Label = uilabel(app.ImageEnhancementTab);
            app.ImageDropDown_2Label.Text = 'Image';
            app.ImageDropDown = uidropdown(app.ImageEnhancementTab);
            app.ImageDropDown.Items = {'GrayScale', 'Colored'};
            app.ImageDropDown.Value = 'GrayScale';
            app.ApplyHistogramEqualizationGrayScaleButton_3 = uibutton(app.ImageEnhancementTab, 'push');
            app.ApplyHistogramEqualizationGrayScaleButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyHistogramEqualizationGrayScaleButtonPushed, true);
            app.ApplyHistogramEqualizationGrayScaleButton_3.Text = 'Apply Histogram Equalization (GrayScale)';
            app.ImageRestorationTab = uitab(app.TabGroup);
            app.ImageRestorationTab.Title = 'Image Restoration';
            app.ImageRestorationTab.ButtonDownFcn = createCallbackFcn(app, @ImageRestorationTabButtonDown, true);
            app.WeinerFilterButton = uibutton(app.ImageRestorationTab, 'push');
            app.WeinerFilterButton.ButtonPushedFcn = createCallbackFcn(app, @WeinerFilterButtonPushed, true);
            app.WeinerFilterButton.Text = 'Weiner Filter';
            app.MeanFilterButton = uibutton(app.ImageRestorationTab, 'push');
            app.MeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanFilterButtonPushed, true);
            app.MeanFilterButton.Text = 'Mean Filter';
            app.MedianFilterButton = uibutton(app.ImageRestorationTab, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.SelectRestorationMethodLabel = uilabel(app.ImageRestorationTab);
            app.SelectRestorationMethodLabel.Text = 'Select Restoration Method';
            app.SelectNoiseLabel = uilabel(app.ImageRestorationTab);
            app.SelectNoiseLabel.Text = 'Select Noise';
            app.SaltPepperButton = uibutton(app.ImageRestorationTab, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.GaussianNoiseButton = uibutton(app.ImageRestorationTab, 'push');
            app.GaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianNoiseButtonPushed, true);
            app.GaussianNoiseButton.Text = 'Gaussian Noise';
            app.PoissonNoiseButton = uibutton(app.ImageRestorationTab, 'push');
            app.PoissonNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @PoissonNoiseButtonPushed, true);
            app.PoissonNoiseButton.Text = 'Poisson Noise';
            app.GaussainFilterButton = uibutton(app.ImageRestorationTab, 'push');
            app.GaussainFilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaussainFilterButtonPushed, true);
            app.GaussainFilterButton.Text = 'Gaussain Filter';
            app.SpeckleNoiseButton = uibutton(app.ImageRestorationTab, 'push');
            app.SpeckleNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @SpeckleNoiseButtonPushed, true);
            app.SpeckleNoiseButton.Text = 'Speckle Noise';
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.FrequencyDomainTab.ButtonDownFcn = createCallbackFcn(app, @FrequencyDomainTabButtonDown, true);
            app.IdealLPFButton = uibutton(app.FrequencyDomainTab, 'push');
            app.IdealLPFButton.ButtonPushedFcn = createCallbackFcn(app, @IdealLPFButtonPushed, true);
            app.IdealLPFButton.Text = 'Ideal LPF';
            app.ButterworthLPFButton = uibutton(app.FrequencyDomainTab, 'push');
            app.ButterworthLPFButton.ButtonPushedFcn = createCallbackFcn(app, @ButterworthLPFButtonPushed, true);
            app.ButterworthLPFButton.Text = 'Butterworth LPF';
            app.GaussainLPFButton = uibutton(app.FrequencyDomainTab, 'push');
            app.GaussainLPFButton.ButtonPushedFcn = createCallbackFcn(app, @GaussainLPFButtonPushed, true);
            app.GaussainLPFButton.Text = 'Gaussain LPF';
            app.ImageSmoothingLabel = uilabel(app.FrequencyDomainTab);
            app.ImageSmoothingLabel.Text = 'Image Smoothing';
            app.ImageSharpeningLabel = uilabel(app.FrequencyDomainTab);
            app.ImageSharpeningLabel.Text = 'Image Sharpening';
            app.IdealHPFButton = uibutton(app.FrequencyDomainTab, 'push');
            app.IdealHPFButton.ButtonPushedFcn = createCallbackFcn(app, @IdealHPFButtonPushed, true);
            app.IdealHPFButton.Text = 'Ideal HPF';
            app.ButterworthHPFButton = uibutton(app.FrequencyDomainTab, 'push');
            app.ButterworthHPFButton.ButtonPushedFcn = createCallbackFcn(app, @ButterworthHPFButtonPushed, true);
            app.ButterworthHPFButton.Text = 'Butterworth HPF';
            app.GaussainHPFButton = uibutton(app.FrequencyDomainTab, 'push');
            app.GaussainHPFButton.ButtonPushedFcn = createCallbackFcn(app, @GaussainHPFButtonPushed, true);
            app.GaussainHPFButton.Text = 'Gaussain HPF';
            % Show the figure after all components are created
            app.DigitalImageProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIPProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DigitalImageProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DigitalImageProcessorUIFigure)
        end
    end
end